classdef HarvestMate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MANUALMODELamp           matlab.ui.control.Lamp
        MANUALMODELampLabel      matlab.ui.control.Label
        Joint7Slider             matlab.ui.control.Slider
        Joint7SliderLabel        matlab.ui.control.Label
        Joint6Slider             matlab.ui.control.Slider
        Joint6SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint4SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        Switch                   matlab.ui.control.RockerSwitch
        ManualModeControlsLabel  matlab.ui.control.Label
        MANUALSwitch             matlab.ui.control.Switch
        Lamp                     matlab.ui.control.Lamp
        GripperSlider            matlab.ui.control.Slider
        GripperSliderLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        STARTButton_2            matlab.ui.control.Button
        ESTOPButton              matlab.ui.control.StateButton
        RobotActionButtonGroup   matlab.ui.container.ButtonGroup
        Button                   matlab.ui.control.ToggleButton
        DualActionButton         matlab.ui.control.ToggleButton
        SortOnlyButton           matlab.ui.control.ToggleButton
        HarvestOnlyButton        matlab.ui.control.ToggleButton
        ProduceButtonGroup       matlab.ui.container.ButtonGroup
        Button_2                 matlab.ui.control.ToggleButton
        MixedButton              matlab.ui.control.ToggleButton
        PotatoesButton           matlab.ui.control.ToggleButton
        TomatoesButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)

        % Robot Related
        harvesterBot;
        sortingBot;
        rightHarvester;
        leftHarvester;
        rightSorter;
        leftSorter;
        steps;
        gripperLength;
        gripperHeight;
        pointDown;
        pointForwards;
        pointBackwards;

        % Produce Related
        tomatoObject;
        tomatoVertices;
        potatoObject;
        potatoVertices;
        producePositions; % Array of positions for each produce item
        produceTags;      % Tags for identifying produce types
    
        % Box locations for sorting
        unsortedBoxMix;
        unsortedBox;
        goodBox;
        badBox;
        unsortedBoxEEPos;
        goodBoxEEPose;
        badBoxEEPose;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            % clear;
            clf;
            pause(0.1);
            clc;
            view(3)
            hold on
            axis([-2.5, 2.5, -2.5, 2.5, 0.01, 2]);            
            disp('Initializing Environment and Robots...');
            
            % Load the environment
            LoadEnvironment();

            % locations for fruit to be placed when dropped
            [app.unsortedBoxMix,app.unsortedBox,app.goodBox,app.badBox] = ProduceClass.BoxLocations();

            app.steps = 50;
            app.gripperLength = 0.24;
            app.gripperHeight = 0.025;

            % Orientation for the end-effector.
            app.pointForwards = trotx(270, 'deg');
            app.pointDown = trotx(180,'deg');
            app.pointBackwards = trotx(90, 'deg');

            % unsorted box position for EE
            app.unsortedBoxEEPos = [0, 0, 0.6]; % box to drop produce
            
            % Sorted box positions for EE
            app.goodBoxEEPose = [0.6, -1.75, 0.6];
            app.badBoxEEPose = [1.1, -1, 0.6];


            [app.harvesterBot, app.sortingBot, app.rightHarvester, app.leftHarvester, app.rightSorter, app.leftSorter] = LoadRobots();

            disp('Environment and Robots Loaded');
            
        end

        % Selection changed function: ProduceButtonGroup
        function ProduceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProduceButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'Tomatoes'
                    [app.producePositions, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.GenerateTomatoes();
                    disp('Generated Tomatoes.');
                case 'Potatoes'
                    [app.producePositions, app.tomatoObject, app.potatoVertices, app.produceTags] = ProduceClass.GeneratePotatoes();
                    disp('Generated Potatoes.');
                case 'Mixed'
                    [app.producePositions, app.potatoObject, app.potatoVertices, app.tomatoObject, app.tomatoVertices, app.produceTags] = ProduceClass.Mix();
                    disp('Generated Mixed Produce.');
            end   
        end

        % Callback function: ProduceButtonGroup, RobotActionButtonGroup
        function ProduceButtonGroupButtonDown(app, event)
            selectedAction = app.RobotActionButtonGroup.SelectedObject.Text;
            switch selectedAction
                case 'Harvest Only'
                    disp('Harvester Started.');
                    StartHarvesting(app);
                case 'Sort Only'
                    disp('Sorter Started.');
                    StartSorting(app);
                case 'Dual Action'
                    disp('Dual Action Started.');
                    StartHarvesting(app);
                    StartSorting(app);
            end
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            RobotClass.ToggleEStop();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ProduceButtonGroup = uibuttongroup(app.UIFigure);
            app.ProduceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupSelectionChanged, true);
            app.ProduceButtonGroup.Title = 'Produce';
            app.ProduceButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.TomatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.TomatoesButton.Text = 'Tomatoes';
            app.PotatoesButton = uitogglebutton(app.ProduceButtonGroup);
            app.PotatoesButton.Text = 'Potatoes';
            app.MixedButton = uitogglebutton(app.ProduceButtonGroup);
            app.MixedButton.Text = 'Mixed';
            app.Button_2 = uitogglebutton(app.ProduceButtonGroup);
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.RobotActionButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProduceButtonGroupButtonDown, true);
            app.RobotActionButtonGroup.Title = 'Robot Action';
            app.HarvestOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.HarvestOnlyButton.Text = 'Harvest Only';
            app.SortOnlyButton = uitogglebutton(app.RobotActionButtonGroup);
            app.SortOnlyButton.Text = 'Sort Only';
            app.DualActionButton = uitogglebutton(app.RobotActionButtonGroup);
            app.DualActionButton.Text = 'Dual Action';
            app.Button = uitogglebutton(app.RobotActionButtonGroup);
            app.Button.Text = '';
            app.Button.Value = true;
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.STARTButton_2 = uibutton(app.UIFigure, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo', 'harvestmateLogo.png');
            app.GripperSliderLabel = uilabel(app.UIFigure);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.UIFigure);
            app.GripperSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.Lamp = uilamp(app.UIFigure);
            app.MANUALSwitch = uiswitch(app.UIFigure, 'slider');
            app.ManualModeControlsLabel = uilabel(app.UIFigure);
            app.ManualModeControlsLabel.Text = 'Manual Mode Controls';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Panda', 'UR3e'};
            app.Switch.Value = 'UR3e';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.MANUALMODELampLabel = uilabel(app.UIFigure);
            app.MANUALMODELampLabel.Text = 'MANUAL MODE';
            app.MANUALMODELamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarvestMate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end